classdef analyze_IRcamera < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        FileMenu          matlab.ui.container.Menu
        FileOpenMenu      matlab.ui.container.Menu
        MainDataDropDown  matlab.ui.control.DropDown
        MainDataLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        DataStruct % すべてのデータを格納する構造体
        MainData % 表示するデータ
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FileOpenMenu
        function FileOpenMenuSelected(app, event)
            data = load_ir_data;
            if size(data,1)==0
                return
            end
            app.DataStruct = data;
            itemsData = struct2cell(app.DataStruct);
            app.MainData = itemsData{1,1};
            % 各種コンポーネントの設定
            app.MainDataDropDown.Items = fieldnames(app.DataStruct); % DropDownのラベル
            app.MainDataDropDown.ItemsData = struct2cell(app.DataStruct); % DropDownの実データ
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'ファイル';
            app.FileOpenMenu = uimenu(app.FileMenu);
            app.FileOpenMenu.MenuSelectedFcn = createCallbackFcn(app, @FileOpenMenuSelected, true);
            app.FileOpenMenu.Accelerator = 'O';
            app.FileOpenMenu.Text = '開く';
            app.MainDataLabel = uilabel(app.UIFigure);
            app.MainDataLabel.Text = '表示するデータ';
            app.MainDataDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyze_IRcamera

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end